classdef threevaronecons < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        GridLayout                matlab.ui.container.GridLayout
        LeftPanel                 matlab.ui.container.Panel
        CenterPanel               matlab.ui.container.Panel
        InputFunctionandConstraintLabel  matlab.ui.control.Label
        For3variables4DgraphisneededwhichcantberealizedLabel  matlab.ui.control.Label
        ThreeVariablesTwoConstraintsLabel  matlab.ui.control.Label
        z_minEditField            matlab.ui.control.NumericEditField
        z_minEditFieldLabel       matlab.ui.control.Label
        z_maxEditField            matlab.ui.control.NumericEditField
        z_maxEditFieldLabel       matlab.ui.control.Label
        x_minEditField            matlab.ui.control.NumericEditField
        x_minEditFieldLabel       matlab.ui.control.Label
        x_maxEditField            matlab.ui.control.NumericEditField
        x_maxEditFieldLabel       matlab.ui.control.Label
        MinimumEditField          matlab.ui.control.NumericEditField
        MinimumEditFieldLabel     matlab.ui.control.Label
        MaximumEditField          matlab.ui.control.NumericEditField
        MaximumEditFieldLabel     matlab.ui.control.Label
        CalculateButton           matlab.ui.control.Button
        ConstraintEditField       matlab.ui.control.EditField
        ConstraintEditFieldLabel  matlab.ui.control.Label
        FunctionEditField         matlab.ui.control.EditField
        FunctionEditFieldLabel    matlab.ui.control.Label
        y_minEditField            matlab.ui.control.NumericEditField
        y_minEditFieldLabel       matlab.ui.control.Label
        y_maxEditField            matlab.ui.control.NumericEditField
        y_maxEditFieldLabel       matlab.ui.control.Label
        RightPanel                matlab.ui.container.Panel
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
        twoPanelWidth = 768;
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
            syms x y z lambda;
            f=app.FunctionEditField.Value;
            func=str2sym(f);
            g=app.ConstraintEditField.Value;
            cons=str2sym(g)==0;
            L = func + lambda*lhs(cons);
            %L=str2sym(l);

            dL_dx=diff(L,x)==0;
            dL_dy=diff(L,y)==0;
            dL_dz=diff(L,z)==0;
            dL_dlambda=diff(L,lambda)==0;
            system = [dL_dx; dL_dy; dL_dz; dL_dlambda];
            [x_val, y_val, z_val, lambda_val] = solve(system, [x y z lambda], 'Real', true);
            results_numeric = double([x_val, y_val, z_val lambda_val]); % show results in a vector of data type double
[row,col]=size(results_numeric);
max=0;
min=0;
a=0;
x_min=results_numeric(1,1);
x_max=results_numeric(1,1);
y_min=results_numeric(1,2);
y_max=results_numeric(1,2);
z_min=results_numeric(1,3);
z_max=results_numeric(1,3);
max=double(subs(subs(subs(func,x,results_numeric(1,1)),y,results_numeric(1,2)),z,results_numeric(1,3)));
min=max;
for i=2:row
    a=double(subs(subs(subs(func,x,results_numeric(i,1)),y,results_numeric(i,2)),z,results_numeric(i,3)));
    if(a>max)
        max=a;
        x_max=results_numeric(i,1);
        y_max=results_numeric(i,2);
        z_max=results_numeric(i,3);
    elseif(a<min)
        min=a;
        x_min=results_numeric(i,1);
        y_min=results_numeric(i,2);
        z_min=results_numeric(i,3);
    end
end

if(max==min)
    test_value=subs(subs(subs(func,x,100),y,100),z,100);
    if(double(test_value)>max)
        max=inf;
        x_max=inf;
        y_max=inf;
        z_max=inf;
    else
        min=-inf;
        x_max=-inf;
        y_max=-inf;
        z_max=-inf;
    end
end

app.MaximumEditField.Value=double(max);
app.MinimumEditField.Value=double(min);
app.x_maxEditField.Value=x_max;
app.x_minEditField.Value=x_min;
app.y_maxEditField.Value=y_max;
app.y_minEditField.Value=y_min;
app.z_maxEditField.Value=z_max;
app.z_minEditField.Value=z_min;

        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 3x1 grid
                app.GridLayout.RowHeight = {496, 496, 496};
                app.GridLayout.ColumnWidth = {'1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 1;
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 3;
                app.RightPanel.Layout.Column = 1;
            elseif (currentFigureWidth > app.onePanelWidth && currentFigureWidth <= app.twoPanelWidth)
                % Change to a 2x2 grid
                app.GridLayout.RowHeight = {496, 496};
                app.GridLayout.ColumnWidth = {'1x', '1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = [1,2];
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 2;
            else
                % Change to a 1x3 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {12, '1x', 12};
                app.LeftPanel.Layout.Row = 1;
                app.LeftPanel.Layout.Column = 1;
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 2;
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 3;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.CenterPanel = uipanel(app.GridLayout);
            app.y_maxEditFieldLabel = uilabel(app.CenterPanel);
            app.y_maxEditFieldLabel.Text = 'y_max';
            app.y_maxEditField = uieditfield(app.CenterPanel, 'numeric');
            app.y_minEditFieldLabel = uilabel(app.CenterPanel);
            app.y_minEditFieldLabel.Text = 'y_min';
            app.y_minEditField = uieditfield(app.CenterPanel, 'numeric');
            app.FunctionEditFieldLabel = uilabel(app.CenterPanel);
            app.FunctionEditFieldLabel.Text = 'Function';
            app.FunctionEditField = uieditfield(app.CenterPanel, 'text');
            app.FunctionEditField.Value = 'x+y+z';
            app.ConstraintEditFieldLabel = uilabel(app.CenterPanel);
            app.ConstraintEditFieldLabel.Text = 'Constraint';
            app.ConstraintEditField = uieditfield(app.CenterPanel, 'text');
            app.ConstraintEditField.Value = 'x^3+y^3+z^3-9';
            app.CalculateButton = uibutton(app.CenterPanel, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.MaximumEditFieldLabel = uilabel(app.CenterPanel);
            app.MaximumEditFieldLabel.Text = 'Maximum';
            app.MaximumEditField = uieditfield(app.CenterPanel, 'numeric');
            app.MinimumEditFieldLabel = uilabel(app.CenterPanel);
            app.MinimumEditFieldLabel.Text = 'Minimum';
            app.MinimumEditField = uieditfield(app.CenterPanel, 'numeric');
            app.x_maxEditFieldLabel = uilabel(app.CenterPanel);
            app.x_maxEditFieldLabel.Text = 'x_max';
            app.x_maxEditField = uieditfield(app.CenterPanel, 'numeric');
            app.x_minEditFieldLabel = uilabel(app.CenterPanel);
            app.x_minEditFieldLabel.Text = 'x_min';
            app.x_minEditField = uieditfield(app.CenterPanel, 'numeric');
            app.z_maxEditFieldLabel = uilabel(app.CenterPanel);
            app.z_maxEditFieldLabel.Text = 'z_max';
            app.z_maxEditField = uieditfield(app.CenterPanel, 'numeric');
            app.z_minEditFieldLabel = uilabel(app.CenterPanel);
            app.z_minEditFieldLabel.Text = 'z_min';
            app.z_minEditField = uieditfield(app.CenterPanel, 'numeric');
            app.ThreeVariablesTwoConstraintsLabel = uilabel(app.CenterPanel);
            app.ThreeVariablesTwoConstraintsLabel.Text = 'Three Variables  Two Constraints';
            app.For3variables4DgraphisneededwhichcantberealizedLabel = uilabel(app.CenterPanel);
            app.For3variables4DgraphisneededwhichcantberealizedLabel.Text = 'For 3 variables 4D graph is needed which can''t be realized .';
            app.InputFunctionandConstraintLabel = uilabel(app.CenterPanel);
            app.InputFunctionandConstraintLabel.Text = 'Input Function and Constraint';
            app.RightPanel = uipanel(app.GridLayout);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = threevaronecons

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end