classdef threevartwocons < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        InputFunctionandConstraintLabel  matlab.ui.control.Label
        ThreeVariablesOneConstraintLabel  matlab.ui.control.Label
        For3variables4DgraphisneededwhichcantberealizedLabel  matlab.ui.control.Label
        z_minEditField         matlab.ui.control.NumericEditField
        z_minEditFieldLabel    matlab.ui.control.Label
        y_minEditField         matlab.ui.control.NumericEditField
        y_minEditFieldLabel    matlab.ui.control.Label
        z_maxEditField         matlab.ui.control.NumericEditField
        z_maxEditFieldLabel    matlab.ui.control.Label
        y_maxEditField         matlab.ui.control.NumericEditField
        y_maxEditFieldLabel    matlab.ui.control.Label
        x_minEditField         matlab.ui.control.NumericEditField
        x_minEditFieldLabel    matlab.ui.control.Label
        x_maxEditField         matlab.ui.control.NumericEditField
        x_maxEditFieldLabel    matlab.ui.control.Label
        FunctionEditField      matlab.ui.control.EditField
        FunctionLabel          matlab.ui.control.Label
        MinimumEditField       matlab.ui.control.NumericEditField
        MinimumEditFieldLabel  matlab.ui.control.Label
        MaximumEditField       matlab.ui.control.NumericEditField
        MaximumEditFieldLabel  matlab.ui.control.Label
        CalculateButton        matlab.ui.control.Button
        Constraint2EditField   matlab.ui.control.EditField
        Constraint2Label       matlab.ui.control.Label
        Constraint1EditField   matlab.ui.control.EditField
        Constraint1Label       matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
            syms x y z lambda sighma
            f=app.FunctionEditField.Value;
            func=str2sym(f);
            g=app.Constraint1EditField.Value;
            cons1=str2sym(g)==0;
            h=app.Constraint2EditField.Value;
            cons2=str2sym(h)==0;
            L=func+lambda*lhs(cons1)+sighma*lhs(cons2);
            dL_dx = diff(L,x) == 0; % derivative of L with respect to x
dL_dy = diff(L,y) == 0;
dL_dz = diff(L,z) == 0;% derivative of L with respect to y 
dL_dlambda = diff(L,lambda) == 0; % derivative of L with respect to lambda
dL_dsigma = diff(L,sighma) == 0; % derivative of L with respect to sigma
system = [dL_dx; dL_dy;dL_dz; dL_dlambda;dL_dsigma]; % build the system of equations
[x_val, y_val,z_val,lambda_val,sigma_val] = solve(system, [x y z lambda sighma], 'Real', true); % solve the system of equations and display the results 
results_numeric = double([x_val, y_val, z_val ,lambda_val,sigma_val]); % show results in a vector of data type double
[row,col]=size(results_numeric);
max=0;
min=0;
a=0;
x_min=results_numeric(1,1);
x_max=results_numeric(1,1);
y_min=results_numeric(1,2);
y_max=results_numeric(1,2);
z_min=results_numeric(1,3);
z_max=results_numeric(1,3);
max=subs(subs(subs(func,x,results_numeric(1,1)),y,results_numeric(1,2)),z,results_numeric(1,3));
min=max;
for i=2:row
    a=subs(subs(subs(func,x,results_numeric(i,1)),y,results_numeric(i,2)),z,results_numeric(i,3));
    if(a>max)
        max=a;
        x_max=results_numeric(i,1);
        y_max=results_numeric(i,2);
        z_max=results_numeric(i,3);
    elseif(a<min)
        min=a;
        x_min=results_numeric(i,1);
        y_min=results_numeric(i,2);
        z_min=results_numeric(i,3);
    end
end
if(max==min)
    test_value=subs(subs(subs(func,x,100),y,100),z,100);
    if(double(test_value)>max)
        max=inf;
        x_max=inf;
        y_max=inf;
        z_max=inf;
    else
        min=-inf;
        x_max=-inf;
        y_max=-inf;
        z_max=-inf;
    end
end

app.MaximumEditField.Value=double(max);
app.MinimumEditField.Value=double(min);
app.x_maxEditField.Value=x_max;
app.x_minEditField.Value=x_min;
app.y_maxEditField.Value=y_max;
app.y_minEditField.Value=y_min;
app.z_maxEditField.Value=z_max;
app.z_minEditField.Value=z_min;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Constraint1Label = uilabel(app.UIFigure);
            app.Constraint1Label.Text = 'Constraint1:';
            app.Constraint1EditField = uieditfield(app.UIFigure, 'text');
            app.Constraint1EditField.Value = '2*x+3*y+6*z';
            app.Constraint2Label = uilabel(app.UIFigure);
            app.Constraint2Label.Text = 'Constraint2: ';
            app.Constraint2EditField = uieditfield(app.UIFigure, 'text');
            app.Constraint2EditField.Value = '4*x+5*y-12';
            app.CalculateButton = uibutton(app.UIFigure, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.MaximumEditFieldLabel = uilabel(app.UIFigure);
            app.MaximumEditFieldLabel.Text = 'Maximum';
            app.MaximumEditField = uieditfield(app.UIFigure, 'numeric');
            app.MinimumEditFieldLabel = uilabel(app.UIFigure);
            app.MinimumEditFieldLabel.Text = 'Minimum';
            app.MinimumEditField = uieditfield(app.UIFigure, 'numeric');
            app.FunctionLabel = uilabel(app.UIFigure);
            app.FunctionLabel.Text = 'Function: ';
            app.FunctionEditField = uieditfield(app.UIFigure, 'text');
            app.FunctionEditField.Value = '(x^2+2*y^2+z^2)^(1/2)';
            app.x_maxEditFieldLabel = uilabel(app.UIFigure);
            app.x_maxEditFieldLabel.Text = 'x_max';
            app.x_maxEditField = uieditfield(app.UIFigure, 'numeric');
            app.x_minEditFieldLabel = uilabel(app.UIFigure);
            app.x_minEditFieldLabel.Text = 'x_min';
            app.x_minEditField = uieditfield(app.UIFigure, 'numeric');
            app.y_maxEditFieldLabel = uilabel(app.UIFigure);
            app.y_maxEditFieldLabel.Text = 'y_max';
            app.y_maxEditField = uieditfield(app.UIFigure, 'numeric');
            app.z_maxEditFieldLabel = uilabel(app.UIFigure);
            app.z_maxEditFieldLabel.Text = 'z_max';
            app.z_maxEditField = uieditfield(app.UIFigure, 'numeric');
            app.y_minEditFieldLabel = uilabel(app.UIFigure);
            app.y_minEditFieldLabel.Text = 'y_min';
            app.y_minEditField = uieditfield(app.UIFigure, 'numeric');
            app.z_minEditFieldLabel = uilabel(app.UIFigure);
            app.z_minEditFieldLabel.Text = 'z_min';
            app.z_minEditField = uieditfield(app.UIFigure, 'numeric');
            app.For3variables4DgraphisneededwhichcantberealizedLabel = uilabel(app.UIFigure);
            app.For3variables4DgraphisneededwhichcantberealizedLabel.Text = 'For 3 variables 4D graph is needed which can''t be realized .';
            app.ThreeVariablesOneConstraintLabel = uilabel(app.UIFigure);
            app.ThreeVariablesOneConstraintLabel.Text = 'Three Variables One Constraint ';
            app.InputFunctionandConstraintLabel = uilabel(app.UIFigure);
            app.InputFunctionandConstraintLabel.Text = 'Input Function and Constraint';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = threevartwocons

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end