classdef twovartwocons < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TwoVariableTwoConstraintsLabel  matlab.ui.control.Label
        GraphofFunctionandConstraintLabel  matlab.ui.control.Label
        InputFunctionandConstraintLabel  matlab.ui.control.Label
        Label                           matlab.ui.control.Label
        y_minEditField                  matlab.ui.control.NumericEditField
        y_minEditFieldLabel             matlab.ui.control.Label
        x_minEditField                  matlab.ui.control.NumericEditField
        x_minEditFieldLabel             matlab.ui.control.Label
        y_maxEditField                  matlab.ui.control.NumericEditField
        y_maxEditFieldLabel             matlab.ui.control.Label
        x_maxEditField                  matlab.ui.control.NumericEditField
        x_maxEditFieldLabel             matlab.ui.control.Label
        MinimumEditField                matlab.ui.control.NumericEditField
        MinimumEditFieldLabel           matlab.ui.control.Label
        MaximumEditField                matlab.ui.control.NumericEditField
        MaximumEditFieldLabel           matlab.ui.control.Label
        CalculateButton                 matlab.ui.control.Button
        Constraint2EditField            matlab.ui.control.EditField
        Constraint2EditFieldLabel       matlab.ui.control.Label
        Constraint1EditField            matlab.ui.control.EditField
        constraint1Label                matlab.ui.control.Label
        FunctionEditField               matlab.ui.control.EditField
        FunctionEditFieldLabel          matlab.ui.control.Label
        UIAxes                          matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
            syms x y lambda sighma
            f=app.FunctionEditField.Value;
            func=str2sym(f);
            g=app.Constraint1EditField.Value;
            cons1=str2sym(g)==0;
            h=app.Constraint2EditField.Value;
            cons2=str2sym(h)==0;
            L = func + lambda * lhs(cons1) + sighma*lhs(cons2);

            dL_dx = diff(L,x) == 0; % derivative of L with respect to x
dL_dy = diff(L,y) == 0;
dL_dlambda = diff(L,lambda) == 0; % derivative of L with respect to lambda
dL_dsigma = diff(L,sighma) == 0; % derivative of L with respect to sigma
system = [dL_dx; dL_dy; dL_dlambda;dL_dsigma]; % build the system of equations
[x_val, y_val,lambda_val,sigma_val] = solve(system, [x y lambda sighma], 'Real', true); % solve the system of equations and display the results 
results_numeric = double([x_val, y_val,lambda_val,sigma_val]); % show results in a vector of data type double
[row,col]=size(results_numeric);
max=0;
min=0;
a=0;
x_min=results_numeric(1,1);
x_max=results_numeric(1,1);
y_min=results_numeric(1,2);
y_max=results_numeric(1,2);
max=subs(subs(func,x,results_numeric(1,1)),y,results_numeric(1,2));
min=max;
for i=2:row
    a=subs(subs(func,x,results_numeric(i,1)),y,results_numeric(i,2));
    if(a>max)
        max=a;
        x_max=results_numeric(i,1);
        y_max=results_numeric(i,2);
    elseif(a<min)
        min=a;
        x_min=results_numeric(i,1);
        y_min=results_numeric(i,2);
    end

end

if(max==min)
    test_value=subs(subs(func,x,100),y,100);
    if(double(test_value)>max)
        max=inf;
        x_max=inf;
        y_max=inf;
    else
        min=-inf;
        x_min=-inf;
        y_min=-inf;
    end
end

app.MaximumEditField.Value=double(max);
app.MinimumEditField.Value=double(min);
app.x_maxEditField.Value=x_max;
app.x_minEditField.Value=x_min;
app.y_maxEditField.Value=y_max;
app.y_minEditField.Value=y_min;

%Graphing

x1=-20:0.5:20;
y1=x1';
[X1,Y1]=meshgrid(x1,y1);
z1 = subs(func, {x, y}, {X1, Y1});
mesh(app.UIAxes,X1,Y1,double(z1));
hold(app.UIAxes,"on");
plot3(app.UIAxes,x_max,y_max,max,"r*")
hold(app.UIAxes,"on");
text(app.UIAxes,x_max,y_max,max,'\leftarrow maximum',Color='r',FontSize=20.0);
hold(app.UIAxes,"on");
plot3(app.UIAxes,x_min,y_min,min,"r*")
hold(app.UIAxes,"on");
text(app.UIAxes,x_min,y_min,min,'\leftarrow minimum',Color='r',FontSize=20.0);
hold(app.UIAxes,"on");
fimplicit(app.UIAxes,cons1,'r--','lineWidth',2.0);
hold(app.UIAxes,"on");
fimplicit(app.UIAxes,cons2,'g--','lineWidth',2.0);
colormap(app.UIAxes,parula(5));
colorbar(app.UIAxes);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Two variabale Two Constraint')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.FunctionEditFieldLabel = uilabel(app.UIFigure);
            app.FunctionEditFieldLabel.Text = 'Function';
            app.FunctionEditField = uieditfield(app.UIFigure, 'text');
            app.FunctionEditField.Value = '1/(1+x^2+y^2)';
            app.constraint1Label = uilabel(app.UIFigure);
            app.constraint1Label.Text = 'Constraint-1';
            app.Constraint1EditField = uieditfield(app.UIFigure, 'text');
            app.Constraint1EditField.Value = 'x+y-10';
            app.Constraint2EditFieldLabel = uilabel(app.UIFigure);
            app.Constraint2EditFieldLabel.Text = 'Constraint-2';
            app.Constraint2EditField = uieditfield(app.UIFigure, 'text');
            app.Constraint2EditField.Value = 'x*y-19';
            app.CalculateButton = uibutton(app.UIFigure, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.MaximumEditFieldLabel = uilabel(app.UIFigure);
            app.MaximumEditFieldLabel.Text = 'Maximum';
            app.MaximumEditField = uieditfield(app.UIFigure, 'numeric');
            app.MinimumEditFieldLabel = uilabel(app.UIFigure);
            app.MinimumEditFieldLabel.Text = 'Minimum';
            app.MinimumEditField = uieditfield(app.UIFigure, 'numeric');
            app.x_maxEditFieldLabel = uilabel(app.UIFigure);
            app.x_maxEditFieldLabel.Text = 'x_max';
            app.x_maxEditField = uieditfield(app.UIFigure, 'numeric');
            app.y_maxEditFieldLabel = uilabel(app.UIFigure);
            app.y_maxEditFieldLabel.Text = 'y_max';
            app.y_maxEditField = uieditfield(app.UIFigure, 'numeric');
            app.x_minEditFieldLabel = uilabel(app.UIFigure);
            app.x_minEditFieldLabel.Text = 'x_min';
            app.x_minEditField = uieditfield(app.UIFigure, 'numeric');
            app.y_minEditFieldLabel = uilabel(app.UIFigure);
            app.y_minEditFieldLabel.Text = 'y_min';
            app.y_minEditField = uieditfield(app.UIFigure, 'numeric');
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = {''; ''};
            app.InputFunctionandConstraintLabel = uilabel(app.UIFigure);
            app.InputFunctionandConstraintLabel.Text = 'Input Function and Constraint';
            app.GraphofFunctionandConstraintLabel = uilabel(app.UIFigure);
            app.GraphofFunctionandConstraintLabel.Text = 'Graph of Function and Constraint';
            app.TwoVariableTwoConstraintsLabel = uilabel(app.UIFigure);
            app.TwoVariableTwoConstraintsLabel.Text = 'Two Variable Two Constraints';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = twovartwocons

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end